classdef LSTMPrediction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        StartofPredictionDatePickerLabel  matlab.ui.control.Label
        StartofPredictionDatePicker    matlab.ui.control.DatePicker
        PredictionhorizonhoursEditFieldLabel  matlab.ui.control.Label
        PredictionhorizonhoursEditField  matlab.ui.control.NumericEditField
        SettingUpPredictionParametersLabel  matlab.ui.control.Label
        FrequencyofsamplingEditFieldLabel  matlab.ui.control.Label
        FrequencyofsamplingEditField   matlab.ui.control.NumericEditField
        NumberofVariablesEditFieldLabel  matlab.ui.control.Label
        NumberofVariablesEditField     matlab.ui.control.NumericEditField
        RunButton                      matlab.ui.control.Button
        TimestepsfortrainingEditFieldLabel  matlab.ui.control.Label
        TimestepsfortrainingEditField  matlab.ui.control.NumericEditField
        PredictionAlgorithmDropDownLabel  matlab.ui.control.Label
        PredictionAlgorithmDropDown    matlab.ui.control.DropDown
        TrainingOptionforLSTMDropDownLabel  matlab.ui.control.Label
        TrainingOptionforLSTMDropDown  matlab.ui.control.DropDown
        DataFileListBoxLabel           matlab.ui.control.Label
        DataFileListBox                matlab.ui.control.ListBox
        StopButton                     matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        UIAxes3                        matlab.ui.control.UIAxes
        SimulationResultsLabel         matlab.ui.control.Label
        MeanRMSEEditFieldLabel         matlab.ui.control.Label
        MeanRMSEEditField              matlab.ui.control.EditField
        MeanJeffreysDivergenceEditFieldLabel  matlab.ui.control.Label
        MeanJeffreysDivergenceEditField  matlab.ui.control.EditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        predHorizon % Description
        sampleSize % Description
        trainingTime % Description
        nPoints
        nVariables
        algorithmScheme
        choiceTraining
        fileData
        LSTMTraining
        startTime
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
          
          if strcmp(app.PredictionAlgorithmDropDown.Value,'LSTM STANDALONE')==1
              PredAlg='LSTM_STANDALONE';
          elseif strcmp(app.PredictionAlgorithmDropDown.Value,'LSTM PATTERN')==1
              PredAlg='LSTM_PATTERN';
          elseif strcmp(app.PredictionAlgorithmDropDown.Value,'1 LSTM & KALMAN FILTER')==1   
              PredAlg='LSTM_PATTERN_KF';
          elseif strcmp(app.PredictionAlgorithmDropDown.Value,'3 LSTM & KALMAN FILTER')==1 
              PredAlg='3LSTM_PATTERN_KF';
          end
          fout=fopen('InPrediction.dat','w');
          fprintf(fout,'%s\n',PredAlg); % six schemes can be used: 'LSTM STANDALONE','LSTM NOUPDATE','KALMAN FILTER', ...
          % '1 LSTM & KALMAN FILTER', '3 LSTM & KALMAN FILTER'
        
          fprintf(fout,'%s\n',app.TrainingOptionforLSTMDropDown.Value); % two choices can be used: 'FULL','PART'

          if isnan(app.TimestepsfortrainingEditField.Value)==1
              uialert(app.UIFigure,message,'Please select number of time steps for training',...
'Icon','Training time steps');
          else
              fprintf(fout,'%d\n',app.TimestepsfortrainingEditField.Value); % length of training interval if 'INTERVAL' training is chosen
          end
          if isnan(posixtime(datetime(app.StartofPredictionDatePicker.Value)))==1
%            uialert(app.UIFigure,message,'Please select start of prediction time',...
%'Icon','Start of Prediction time');  
          else
             fprintf(fout,'%s\n',app.StartofPredictionDatePicker.Value); %start time in format "2018-07-18 00:00:30" for prediction     
          end 
          if isnan(app.PredictionhorizonhoursEditField.Value)==1
 %           uialert(app.UIFigure,message,'Please define time of prediction (in hours)',...
%'Icon','Prediction time');  
          else
            fprintf(fout,'%d\n',app.PredictionhorizonhoursEditField.Value); % prediction horizon (in hours);
          end
          if isnan(app.TimestepsfortrainingEditField.Value)==1
 %           uialert(app.UIFigure,message,'Please define prediction horizon (in hours)',...
%'Icon','Prediction time');  
          else
            fprintf(fout,'%d\n',app.TimestepsfortrainingEditField.Value); % frequency for low rate sampling/averaging of data;    
          end
          if isnan(app.NumberofVariablesEditField.Value)==1
%            uialert(app.UIFigure,message,'Pleаse chose number of variables',...
%'Icon','Number of variables');  
          else
            fprintf(fout,'%d\n',app.NumberofVariablesEditField.Value); % number of parameters     
          end
          
           fprintf(fout,'%s\n',app.DataFileListBox.Value); % name of data file   

           fclose(fout); 
            dirCurrent = 'C:/Users/eshchekinova/Documents/BoknisData/LSTMPred/Model';
            dirNetwork = '../LSTMNetwork';
            dirData = '../PredictionData';
            
            LSTMfromUI('InPrediction.dat',dirCurrent,dirNetwork,dirData);
            
            cd(dirData); % change directory to the one containing LSTM predictions
            
            strcat(Algorithm_Scheme,'_',choice_training,'_',num2str(sampleSize),'_',num2str(n_points),'_',num2str(nVar))
            
            FileName=strcat(PredAlg,'_',app.TrainingOptionforLSTMDropDown.Value,'_',app.TimestepsfortrainingEditField.Value,'_',...
                num2str(app.FrequencyofsamplingEditField.Value),'_',num2str(app.NumberofVariablesEditField.Value),'.mat');
         
            load(FileName,'-mat'); % load file with original time series X_true, filtered time series X_filter and predicted X_f

            nVar_f=length(X_true(1,:));
            s1 = 1;
            for i=1:length(t_true)
              for k=1:length(X_f)
                if (t_true(i)==t_f(k))
                  rmse(s1,1:nVar_f)=abs(X_f(k,1:nVar_f)-X_true(i,1:nVar_f)); % calculate RMSE for available prediction
                  X_original(s1,1:nVar_f)=X_true(i,1:nVar_f); % create a copy of original time series 
                  time(s1)=t_true(i); % create a copy of time series 
                 end;
              end;
            end
            lengthT=length(time);
            X1_timeseries=X_original;
            X2_timeseries=X_f;

            cd(dirCurrent); % change back to current directory 

            D_J = divergenceJeffrey(X1_timeseries,X2_timeseries); % calculate Jeffrey's divergence between original and predicted data 
           for i=1:nVar_f
             mean_rmse(i)=mean(rmse(:,i));
             mean_DJ(i)=mean(D_J(:,i));
           end;
            
           app.MeanRMSEEditField.Value = num2str(mean_rmse(1));
           app.MeanJeffreysDivergenceEditField.Value=num2str(mean_DJ(1));
           % Convert seconds to date
           e = datenum('01-01-1970 00:00:00');
           for i=1:length(time)
              time_date(i) = datetime(datestr(e+time(i)/86400,'dd-mmm-yyyy hh:MM:ss'));
           end;
            
           plot(app.UIAxes3,time,X_original(:,1),'r',time,X_f(:,1),'g');
           xtickangle(app.UIAxes3,45);
           %xlabel(app.UIAxes3,time_date);
           title(app.UIAxes3,'Original and predicted data');
           xlim(app.UIAxes3,[time(1),time(end)]);
           legend(app.UIAxes3,'original','prediction');
            

          %  clear all;

        end

        % Value changed function: PredictionhorizonhoursEditField
        function PredictionhorizonhoursEditFieldValueChanged(app, event)
            value = app.PredictionhorizonhoursEditField.Value;
            app.predHorizon=value;
        end

        % Callback function
        function LSTMAlgorithmChoiceListBoxValueChanged(app, event)
            value = app.LSTMAlgorithmChoiceListBox.Value;
            
        end

        % Callback function
        function LSTMTrainingListBoxValueChanged(app, event)
            value = app.LSTMTrainingListBox.Value;
            
        end

        % Value changed function: FrequencyofsamplingEditField
        function FrequencyofsamplingEditFieldValueChanged(app, event)
            value = app.FrequencyofsamplingEditField.Value;
            app.nPoints = value;
        end

        % Callback function
        function EnsemblesizeEditFieldValueChanged(app, event)
            value = app.EnsemblesizeEditField.Value;
            app.sampleSize=value;
        end

        % Value changed function: NumberofVariablesEditField
        function NumberofVariablesEditFieldValueChanged(app, event)
            value = app.NumberofVariablesEditField.Value;
            app.nVariables = value;
        end

        % Callback function
        function DataFileListBoxValueChanged(app, event)
            value = app.DataFileListBox.Value;
            app.fileData = value;
        end

        % Callback function
        function TrainingOptionListBox_2ValueChanged(app, event)
            value = app.TrainingOptionListBox_2.Value;
            app.choiceTraining = value;
        end

        % Value changed function: StartofPredictionDatePicker
        function StartofPredictionDatePickerValueChanged(app, event)
            value = app.StartofPredictionDatePicker.Value;
            app.startTime = value;
        end

        % Value changed function: TimestepsfortrainingEditField
        function TimestepsfortrainingEditFieldValueChanged(app, event)
            value = app.TimestepsfortrainingEditField.Value;
            app.trainingTime =value;
        end

        % Callback function
        function LSTMAlgorithmButtonGroupSelectionChanged(app, event)
            selectedButton = app.LSTMAlgorithmButtonGroup.SelectedObject;
            app.algorithmScheme = selectedButton;
        end

        % Callback function
        function TrainingOptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrainingOptionButtonGroup.SelectedObject;
            app.choiceTraining = selectedButton;
        end

        % Callback function
        function LSTMTrainingButtonGroupSelectionChanged(app, event)
            selectedButton = app.LSTMTrainingButtonGroup.SelectedObject;
            app.LSTMTraining = selectedButton;
        end

        % Callback function
        function DataFileButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataFileButtonGroup.SelectedObject;
            app.fileData = selectedButton;
        end

        % Value changed function: PredictionAlgorithmDropDown
        function PredictionAlgorithmDropDownValueChanged(app, event)
            value = app.PredictionAlgorithmDropDown.Value;
        end

        % Value changed function: TrainingOptionforLSTMDropDown
        function TrainingOptionforLSTMDropDownValueChanged(app, event)
            value = app.TrainingOptionforLSTMDropDown.Value;
            app.choiceTraining = value;
        end

        % Callback function
        function LSTMTrainingDropDownValueChanged(app, event)
            value = app.LSTMTrainingDropDown.Value;
            app.LSTMTraining = value;
        end

        % Value changed function: DataFileListBox
        function DataFileListBoxValueChanged2(app, event)
            value = app.DataFileListBox.Value;
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            return;
        end

        % Value changed function: MeanRMSEEditField
        function MeanRMSEEditFieldValueChanged(app, event)
            value = app.MeanRMSEEditField.Value;
            
        end

        % Value changed function: MeanJeffreysDivergenceEditField
        function MeanJeffreysDivergenceEditFieldValueChanged(app, event)
            value = app.MeanJeffreysDivergenceEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {303, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartofPredictionDatePickerLabel = uilabel(app.LeftPanel);
            app.StartofPredictionDatePickerLabel.Text = 'Start of Prediction';
            app.StartofPredictionDatePicker = uidatepicker(app.LeftPanel);
            app.StartofPredictionDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartofPredictionDatePickerValueChanged, true);
            app.PredictionhorizonhoursEditFieldLabel = uilabel(app.LeftPanel);
            app.PredictionhorizonhoursEditFieldLabel.Text = 'Prediction horizon (hours)';
            app.PredictionhorizonhoursEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PredictionhorizonhoursEditField.ValueChangedFcn = createCallbackFcn(app, @PredictionhorizonhoursEditFieldValueChanged, true);
            app.SettingUpPredictionParametersLabel = uilabel(app.LeftPanel);
            app.SettingUpPredictionParametersLabel.Text = 'Setting Up Prediction Parameters ';
            app.FrequencyofsamplingEditFieldLabel = uilabel(app.LeftPanel);
            app.FrequencyofsamplingEditFieldLabel.Text = 'Frequency of sampling';
            app.FrequencyofsamplingEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrequencyofsamplingEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyofsamplingEditFieldValueChanged, true);
            app.FrequencyofsamplingEditField.Value = 10;
            app.NumberofVariablesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofVariablesEditFieldLabel.Text = 'Number of Variables';
            app.NumberofVariablesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofVariablesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofVariablesEditFieldValueChanged, true);
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TimestepsfortrainingEditFieldLabel = uilabel(app.LeftPanel);
            app.TimestepsfortrainingEditFieldLabel.Text = 'Time steps for training';
            app.TimestepsfortrainingEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TimestepsfortrainingEditField.ValueChangedFcn = createCallbackFcn(app, @TimestepsfortrainingEditFieldValueChanged, true);
            app.PredictionAlgorithmDropDownLabel = uilabel(app.LeftPanel);
            app.PredictionAlgorithmDropDownLabel.Text = 'Prediction Algorithm';
            app.PredictionAlgorithmDropDown = uidropdown(app.LeftPanel);
            app.PredictionAlgorithmDropDown.Items = {'LSTM STANDALONE', 'LSTM PATTERN', '1 LSTM & KALMAN FILTER', '3 LSTM & KALMAN FILTER'};
            app.PredictionAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @PredictionAlgorithmDropDownValueChanged, true);
            app.PredictionAlgorithmDropDown.Value = 'LSTM STANDALONE';
            app.TrainingOptionforLSTMDropDownLabel = uilabel(app.LeftPanel);
            app.TrainingOptionforLSTMDropDownLabel.Text = 'Training Option (for LSTM )';
            app.TrainingOptionforLSTMDropDown = uidropdown(app.LeftPanel);
            app.TrainingOptionforLSTMDropDown.Items = {'PART', 'FULL'};
            app.TrainingOptionforLSTMDropDown.ValueChangedFcn = createCallbackFcn(app, @TrainingOptionforLSTMDropDownValueChanged, true);
            app.TrainingOptionforLSTMDropDown.Value = 'PART';
            app.DataFileListBoxLabel = uilabel(app.LeftPanel);
            app.DataFileListBoxLabel.Text = 'Data File';
            app.DataFileListBox = uilistbox(app.LeftPanel);
            app.DataFileListBox.Items = {'NormalizedBoknis'};
            app.DataFileListBox.ValueChangedFcn = createCallbackFcn(app, @DataFileListBoxValueChanged2, true);
            app.DataFileListBox.Value = 'NormalizedBoknis';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Original and Predicted Time Series')
            xlabel(app.UIAxes3, 'Date')
            ylabel(app.UIAxes3, 'Normalized Data')
            app.SimulationResultsLabel = uilabel(app.RightPanel);
            app.SimulationResultsLabel.Text = 'Simulation Results';
            app.MeanRMSEEditFieldLabel = uilabel(app.RightPanel);
            app.MeanRMSEEditFieldLabel.Text = 'Mean RMSE';
            app.MeanRMSEEditField = uieditfield(app.RightPanel, 'text');
            app.MeanRMSEEditField.ValueChangedFcn = createCallbackFcn(app, @MeanRMSEEditFieldValueChanged, true);
            app.MeanJeffreysDivergenceEditFieldLabel = uilabel(app.RightPanel);
            app.MeanJeffreysDivergenceEditFieldLabel.Text = 'Mean Jeffreys Divergence';
            app.MeanJeffreysDivergenceEditField = uieditfield(app.RightPanel, 'text');
            app.MeanJeffreysDivergenceEditField.ValueChangedFcn = createCallbackFcn(app, @MeanJeffreysDivergenceEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LSTMPrediction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end